classdef Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ad_leftEditField             matlab.ui.control.NumericEditField
        ad_leftEditFieldLabel        matlab.ui.control.Label
        Ti_leftEditField             matlab.ui.control.NumericEditField
        Ti_leftEditFieldLabel        matlab.ui.control.Label
        K_leftEditField              matlab.ui.control.NumericEditField
        K_leftEditFieldLabel         matlab.ui.control.Label
        BaudRateEditField            matlab.ui.control.EditField
        BaudRateEditFieldLabel       matlab.ui.control.Label
        SerialPortEditField          matlab.ui.control.EditField
        SerialPortEditFieldLabel     matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        AutomaticReferencesCheckBox  matlab.ui.control.CheckBox
        REFEditField                 matlab.ui.control.NumericEditField
        REFEditFieldLabel            matlab.ui.control.Label
        ad_rightEditField            matlab.ui.control.NumericEditField
        ad_rightEditFieldLabel       matlab.ui.control.Label
        Ti_rightEditField            matlab.ui.control.NumericEditField
        Ti_rightEditFieldLabel       matlab.ui.control.Label
        K_rightEditField             matlab.ui.control.NumericEditField
        K_rightEditFieldLabel        matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        SerialPort                      % Handle to serialport object
        t 
        y
        Plot_Measure_left_ref
        Plot_Measure_left_vel                            % Handle to "Plot View" line object
        Plot_Measure_right_ref                            % Handle to "Plot View" line object
        Plot_Measure_right_vel
        P = 10
        I = 1
        D = 0
        REF = 0
        automatic_references 
        
   
    end
    methods (Access = private)
        
        function serialportCallback(app,obj,evt)
            %   serialportCallback serves as the callback when new data is
            %   received by the serialport object, as set by configureCallback
            %   This callback controls the app flow based on the app states

            % Handle Data state
            inputline = readline(obj);
            if strlength(inputline) > 50
                str_ = strsplit(inputline,"\t");
                time = str_(1);
                time = strsplit(time,":");
                time = str2double(time(2));
                app.t = [app.t ; time];
                ref = str_(5);
                ref = strsplit(ref,":");
                ref = str2double(ref(2));
                meas_right = str_(3);
                meas_right = strsplit(meas_right,":");
                meas_right = str2double(meas_right(2));

                meas_left = str_(4);
                meas_left = strsplit(meas_left,":");
                meas_left = str2double(meas_left(2));
                app.y = [app.y ; ref meas_left meas_right];
                % Update Live View state
                set(app.Plot_Measure_left_ref,"XData",app.t,"YData",app.y(:,1));
                hold(app.UIAxes,"on");
                set(app.Plot_Measure_left_vel,"XData",app.t,"YData",app.y(:,2));
                hold(app.UIAxes,"off");

                
                set(app.Plot_Measure_right_ref,"XData",app.t,"YData",app.y(:,1));
                hold(app.UIAxes_2,"on");
                set(app.Plot_Measure_right_vel,"XData",app.t,"YData",app.y(:,3));
                hold(app.UIAxes_2,"off");
                %drawnow
            end
        end

        
        function setAppState(app,state)
            %setAppState enables and disables buttons based on the state that
            %   the app is in

            switch state
                case "DeviceSelection"
                    % Only connect, baud rate, serial port, and file name
                    % select are enabled disable Start logging button
                    app.SerialPortEditField.Enable = "on";
                    app.BaudRateEditField.Enable = "on";
                    app.ConnectButton.Enable = "on";
                    app.ConnectButton.Text = "Connect";

                    app.Lamp.Color = [1.00,0.00,0.00]; %Red
                    %[0.00,1.00,0.00]; %Green
                    
                    
                    app.Plot_Measure_left_ref = plot(app.UIAxes,0,0,'Color',"#0072BD");
                    hold(app.UIAxes,"on");
                    app.Plot_Measure_left_vel = plot(app.UIAxes,0,0,'Color',"#D95319");
                    hold(app.UIAxes,"off");

                    
                    app.Plot_Measure_right_ref = plot(app.UIAxes_2,0,0,'Color',"#0072BD");
                    hold(app.UIAxes_2,"on");
                    app.Plot_Measure_right_vel = plot(app.UIAxes_2,0,0,'Color',"#D95319");
                    hold(app.UIAxes_2,"off");


                    ylim(app.UIAxes,"padded")
                    xlim(app.UIAxes,"padded")

                    ylim(app.UIAxes_2,"padded")
                    xlim(app.UIAxes_2,"padded")

                    app.Ti_rightEditField.Enable = "off";
                    app.K_rightEditField.Enable = "off";
                    app.ad_rightEditField.Enable = "off";
                    app.REFEditField.Enable = "off";
                    app.AutomaticReferencesCheckBox.Enable = "off";


                    app.SerialPort = []

                    app.t = [];
                    app.y = [];
                    
                case "Connected"
                    % Enable everything, disable the "configuration"
                    % options (except for disconnect)
                    app.SerialPortEditField.Enable = "off";
                    app.BaudRateEditField.Enable = "off";
                    app.ConnectButton.Enable = "on";

                    app.ConnectButton.Text = "Disconnect";

                    
                    app.Plot_Measure_left_ref = plot(app.UIAxes,0,0,'Color',"#0072BD");
                    hold(app.UIAxes,"on")
                    app.Plot_Measure_left_vel = plot(app.UIAxes,0,0,'Color',"#D95319");
                    hold(app.UIAxes,"off")

                    
                    app.Plot_Measure_right_ref = plot(app.UIAxes_2,0,0,'Color',"#0072BD");
                    hold(app.UIAxes_2,'on');
                    app.Plot_Measure_right_vel = plot(app.UIAxes_2,0,0,'Color',"#D95319");
                    hold(app.UIAxes_2,'off');
                    ylim(app.UIAxes,"padded")
                    xlim(app.UIAxes,"padded")

                    ylim(app.UIAxes_2,"padded")
                    xlim(app.UIAxes_2,"padded")

                    app.Ti_rightEditField.Enable = "on";
                    app.K_rightEditField.Enable = "on";
                    app.ad_rightEditField.Enable = "on";
                    app.REFEditField.Enable = "on";
                    app.AutomaticReferencesCheckBox.Enable = "on";

                    app.Lamp.Color = [0.00,1.00,0.00]; %Red
                    app.t = [];
                    app.y = [];
                    %[0.00,1.00,0.00]; %Green
            end
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: K_rightEditField
        function K_rightEditFieldValueChanged(app, event)
            app.P = app.K_rightEditField.Value;
            to_send = sprintf("K_right:%f",app.P);
            writeline(app.SerialPort,to_send)
        end

        % Value changed function: Ti_rightEditField
        function Ti_rightEditFieldValueChanged(app, event)
            app.I = app.Ti_rightEditField.Value;
            to_send = sprintf("Ti_right:%f",app.I);
            writeline(app.SerialPort,to_send)
            
        end

        % Value changed function: ad_rightEditField
        function ad_rightEditFieldValueChanged(app, event)
            app.D = app.ad_rightEditField.Value;
            to_send = sprintf("ad_right:%f",app.D);
            writeline(app.SerialPort,to_send)

            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            if strcmp(app.ConnectButton.Text, "Connect")
                setAppState(app,"Connected");
                drawnow
    
                % Validate selected serial port device
                try
                    app.SerialPort = serialport(app.SerialPortEditField.Value,str2double(app.BaudRateEditField.Value));
                    configureTerminator(app.SerialPort,"CR/LF")
                catch ME
                    uialert(app.UIFigure,ME.message,"Serialport Connection Error",Interpreter="html")
                    setAppState(app,"DeviceSelection");
                    return
                end
    
                % Set terminator and callback
                configureTerminator(app.SerialPort,"CR/LF")
                configureCallback(app.SerialPort,"terminator", @(obj,event) serialportCallback(app,obj,event))
   
            else
                setAppState(app,"DeviceSelection");
                app.SerialPort = [];
            end
        end

        % Callback function
        function RefreshButtonPushed(app, event)
             app.SerialPortEditField.Items = cellstr(["" serialportlist]);
        end

        % Value changed function: REFEditField
        function REFEditFieldValueChanged(app, event)
            app.REF = app.REFEditField.Value;
            to_send = sprintf("REF:%f",app.REF);
            writeline(app.SerialPort,to_send)
        end

        % Value changed function: AutomaticReferencesCheckBox
        function AutomaticReferencesCheckBoxValueChanged(app, event)
            app.automatic_references = app.AutomaticReferencesCheckBox.Value;
            to_send = sprintf("Auto:%d",app.automatic_references)
            writeline(app.SerialPort,to_send)
            if app.automatic_references
                 to_send = sprintf("REF:%f",0)
                 writeline(app.SerialPort,to_send);
                app.REFEditField.Enable = "off";
            else
                app.REFEditField.Enable = "on";
            end
            
            
        end

        % Value changed function: K_leftEditField
        function K_leftEditFieldValueChanged(app, event)
            app.P = app.K_leftEditField.Value;
            to_send = sprintf("K_left:%f",app.P);
            writeline(app.SerialPort,to_send)
            
        end

        % Value changed function: Ti_leftEditField
        function Ti_leftEditFieldValueChanged(app, event)
            app.I = app.Ti_leftEditField.Value;
            to_send = sprintf("Ti_left:%f",app.I);
            writeline(app.SerialPort,to_send)
            
        end

        % Value changed function: ad_leftEditField
        function ad_leftEditFieldValueChanged(app, event)
            app.D = app.ad_rightEditField.Value;
            to_send = sprintf("ad_left:%f",app.D);
            writeline(app.SerialPort,to_send)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Left Controller Performance')
            xlabel(app.UIAxes, 'Time - [s]')
            ylabel(app.UIAxes, 'Motor speed - [rpm]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Right Controller Performance')
            xlabel(app.UIAxes_2, 'Time - [s]')
            ylabel(app.UIAxes_2, 'Motor speed - [rpm]')
            zlabel(app.UIAxes_2, 'Z')
            app.K_rightEditFieldLabel = uilabel(app.UIFigure);
            app.K_rightEditFieldLabel.Text = 'K_right';
            app.K_rightEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_rightEditField.ValueChangedFcn = createCallbackFcn(app, @K_rightEditFieldValueChanged, true);
            app.K_rightEditField.Value = 10;
            app.Ti_rightEditFieldLabel = uilabel(app.UIFigure);
            app.Ti_rightEditFieldLabel.Text = 'Ti_right';
            app.Ti_rightEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ti_rightEditField.ValueChangedFcn = createCallbackFcn(app, @Ti_rightEditFieldValueChanged, true);
            app.Ti_rightEditField.Value = 1;
            app.ad_rightEditFieldLabel = uilabel(app.UIFigure);
            app.ad_rightEditFieldLabel.Text = 'ad_right';
            app.ad_rightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ad_rightEditField.ValueChangedFcn = createCallbackFcn(app, @ad_rightEditFieldValueChanged, true);
            app.REFEditFieldLabel = uilabel(app.UIFigure);
            app.REFEditFieldLabel.Text = 'REF';
            app.REFEditField = uieditfield(app.UIFigure, 'numeric');
            app.REFEditField.ValueChangedFcn = createCallbackFcn(app, @REFEditFieldValueChanged, true);
            app.AutomaticReferencesCheckBox = uicheckbox(app.UIFigure);
            app.AutomaticReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticReferencesCheckBoxValueChanged, true);
            app.AutomaticReferencesCheckBox.Text = 'Automatic References';
            app.AutomaticReferencesCheckBox.Value = true;
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'text');
            app.BaudRateEditField.Value = '57600';
            app.K_leftEditFieldLabel = uilabel(app.UIFigure);
            app.K_leftEditFieldLabel.Text = 'K_left';
            app.K_leftEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_leftEditField.ValueChangedFcn = createCallbackFcn(app, @K_leftEditFieldValueChanged, true);
            app.K_leftEditField.Value = 10;
            app.Ti_leftEditFieldLabel = uilabel(app.UIFigure);
            app.Ti_leftEditFieldLabel.Text = 'Ti_left';
            app.Ti_leftEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ti_leftEditField.ValueChangedFcn = createCallbackFcn(app, @Ti_leftEditFieldValueChanged, true);
            app.Ti_leftEditField.Value = 1;
            app.ad_leftEditFieldLabel = uilabel(app.UIFigure);
            app.ad_leftEditFieldLabel.Text = 'ad_left';
            app.ad_leftEditField = uieditfield(app.UIFigure, 'numeric');
            app.ad_leftEditField.ValueChangedFcn = createCallbackFcn(app, @ad_leftEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end